classdef grid_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooseGridParametersUIFigure  matlab.ui.Figure
        GridNameEditFieldLabel        matlab.ui.control.Label
        GridNameEditField             matlab.ui.control.EditField
        AnchorPointPanel              matlab.ui.container.Panel
        XorLonEditFieldLabel          matlab.ui.control.Label
        XAnchor                       matlab.ui.control.NumericEditField
        YorLatEditFieldLabel          matlab.ui.control.Label
        YAnchor                       matlab.ui.control.NumericEditField
        ZLimitsdownLabel_2            matlab.ui.control.Label
        ZLimits                       matlab.ui.control.EditField
        SeparationPanel               matlab.ui.container.Panel
        dxEditFieldLabel              matlab.ui.control.Label
        dx                            matlab.ui.control.NumericEditField
        dyEditFieldLabel              matlab.ui.control.Label
        dy                            matlab.ui.control.NumericEditField
        dzLabel                       matlab.ui.control.Label
        dz                            matlab.ui.control.NumericEditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        FollowMeridiansCheckBox       matlab.ui.control.CheckBox
        OKButton                      matlab.ui.control.Button
        DemoGrid                      matlab.ui.control.UIAxes
        LoadButton                    matlab.ui.control.Button
        SaveOKButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        RefEllipsoidLabel             matlab.ui.control.Label
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = private)
        AnchorPoint % Grid initiates at this point
        GridPoints % All points for the grid
        GridDirectory % Load and Save location for grid
        RefEllipsoid referenceEllipsoid = referenceEllipsoid('wgs84','kilometer');

    end
    properties(Constant, Access = private)
       ErrorColor = [1 0.95 0.95]
    end
    
    properties
        ResultDump =[] % function to send results to as (g, gridopt)
    end
    
    properties (Dependent)
        GridOpts % Grid options
    end

    methods
        function gridopt = get.GridOpts(app)
            % create the GridOptions object
            if app.UnitsDropDown.Value == "degree"
            gridopt = GridOptions('XY',[app.dx.Value, app.dy.Value, app.dz.Value],...
                app.UnitsDropDown.Value, app.FollowMeridiansCheckBox.Value);
            else
            gridopt = GridOptions('XY',[app.dx.Value, app.dy.Value, app.dz.Value],...
                app.RefEllipsoid, app.FollowMeridiansCheckBox.Value);
            end
        end
        
        function set.GridOpts(app, gridopt)
            
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            
            if ~ isempty(gridopt.dz)
                app.dz.Value = gridopt.dz;
            else
                app.dz.Value = 1;
            end
            
            if startsWith(gridopt.horizUnits,"deg",'IgnoreCase',true)
                app.UnitsDropDown.Value = 'degree';
            else
                app.UnitsDropDown.Value = validateLengthUnit(gridopt.horizUnits);
            end
            
            app.FollowMeridiansCheckBox.Value = logical(gridopt.followMeridians);
            app.UpdateGridPoints;
        end
        
        function set.ResultDump(app, fcn)
            assert(isa(fcn,'function_handle') || isempty(fcn))
            assert(nargin(fcn)==2 || isempty(fcn));
            app.ResultDump=fcn;
        end
        
    end
    
    methods (Access = private)
    
        function [lonMat, latMat] = get_eq_grid(app)
            lon0 = app.XAnchor.Value;
            lat0 = app.YAnchor.Value;
            dLon = app.dx.Value;
            dLat = app.dy.Value;
            % GET_EQ_GRID
            % input is the origin point and arclength between points
            % output is 2 matrices (lon, lat)
            
            if app.UnitsDropDown.Value ~= "degree"
                myratio = unitsratio('kilometer', app.UnitsDropDown.Value); % unitsratio(TO , FROM)
                dLon_deg = km2deg(dLon .* myratio);
                dLat_deg = km2deg(dLat .* myratio);
            else
                dLon_deg = dLon;
                dLat_deg = dLat;
            end
                
            % use the axes limits (assumed degrees) to control size of grid
            ylims_deg = app.DemoGrid.YLim;
            xlims_deg = app.DemoGrid.XLim;
            
            lonMat=[];
            latMat=[];
            
            nPointsApprox=round(((range(ylims_deg) / dLat_deg) * (range(xlims_deg) / dLon_deg)));
            if nPointsApprox > 1e6 || nPointsApprox < 4
                if nPointsApprox > 1e6
                    msg = sprintf('Too many points ~[%d]. Grid will not recalculate until larger dx or dy are given',nPointsApprox);
                else
                    msg = sprintf('Too few points [%d]. Grid will not recalculate until smaller dx or dy are given',nPointsApprox);
                    dbstack
                end
                uialert(app.ChooseGridParametersUIFigure,msg,'Invalid grid');
                app.DemoGrid.BackgroundColor = app.ErrorColor;
                app.dx.BackgroundColor = app.ErrorColor;
                app.dy.BackgroundColor = app.ErrorColor;
                return
            else
                app.DemoGrid.BackgroundColor=[1 1 1];
                app.dx.BackgroundColor=[1 1 1];
                app.dy.BackgroundColor=[1 1 1];
            end
            
            % pick out latitude spacing. Our grid will have this many rows.
            lats = app.vector_including_origin(lat0, dLat_deg, ylims_deg);
            
            if app.UnitsDropDown.Value == "degree"
                reckoning_fn =@(la) reckon('rh',la, 0, dLon_deg, 90);
            else
                reckoning_fn =@(la) reckon('rh',la, 0, dLon, 90, app.RefEllipsoid);
            end

            if app.FollowMeridiansCheckBox.Visible=="on" &&  app.FollowMeridiansCheckBox.Value
                % when following the meridian lines, the longitude span covered by the arc-distance at lat0 (along the rhumb!) remains constant.
                [~,dLon] = reckoning_fn(lat0);
                lonValues = app.vector_including_origin(lon0, dLon_deg, xlims_deg);
                [lonMat,latMat]=meshgrid(lonValues,lats); % size: lonValues x lats
                
            else
                % when ignoring meridian lines, and aiming for an approximately constant distance, the dLon at each latitude will differ.
                [~,dLon_per_lat] = reckoning_fn(lats);
                    
                for n=1:numel(lats)
                    theseLonValues = app.vector_including_origin(lon0, dLon_per_lat(n), xlims_deg);
                    lonMat=[lonMat;theseLonValues(:)]; %#ok<AGROW>
                    latMat=[latMat;repmat(lats(n),size(theseLonValues(:)))]; %#ok<AGROW>
                end
                
                [lonMat,latMat] = cols2matrix(app.XAnchor.Value, lonMat,latMat);
                % each gridx & gridy are vectors.
            end
            
        end
        
            
        function v = vector_including_origin(~, orig_deg, delta_deg, lims_deg)
            v = unique([orig_deg : -delta_deg : min(lims_deg) , orig_deg : delta_deg :max(lims_deg)]);
        end
        
        
        function g = CreateGrid(app)
            gopParams = {'XY',...
                [app.dx.Value, app.dy.Value],...
                app.RefEllipsoid};
            if app.FollowMeridiansCheckBox.Value
                gopParams(end+1)={'FollowMeridians'};
            end
            
            gop = GridOptions(gopParams{:});
            gop.FixedAnchorPoint = [app.XAnchor.Value, app.YAnchor.Value];
            
            g = ZmapGrid(app.GridNameEditField.Value,"FromGridOptions", gop);
        end
        
        function SetGridOptions(app, g, gridopt)
            app.GridNameEditField.Value = g.Name;
            switch lower(g.Units)
                case 'degree'
                    app.UnitsDropDown.Value='degree';
                otherwise
                    app.Units.DropDown.Value=validateLengthUnit(g.Units);
            end
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            app.dz.Value = gridopt.dz;
            app.FollowMeridiansCheckBox.Value=startsWith(gridopt.dx_units,"deg",'IgnoreCase',true);
            disp(gridopt);
        end
        
        function DumpTheResult(app)
            if ~isempty(app.ResultDump)
                app.ResultDump(app.CreateGrid, app.GridOpts)
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ellipsoid, gridopt)
            ZG = ZmapGlobal.Data;
            if exist('ellipsoid','var')
                app.RefEllipsoid = ellipsoid;
            end
            app.RefEllipsoidLabel.Text = "Ref: " + app.RefEllipsoid.Name;
            app.GridDirectory = fullfile(ZG.Directories.data,'grids');
            if ~exist('gridopt','var')
                gridopt = ZG.gridopt;
            end
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            app.dz.Value = gridopt.dz;
            
            % synchronize the reference ellipsoid, length units, and the units drop-down
            if startsWith(gridopt.horizUnits,"deg",'IgnoreCase',true)
                len_unit = 'degree';
                app.RefEllipsoid.LengthUnit = 'kilometer';
                app.FollowMeridiansCheckBox.Visible = 'on';
            else
                len_unit = validateLengthUnit(gridopt.horizUnits);
                app.RefEllipsoid.LengthUnit = len_unit;
                app.FollowMeridiansCheckBox.Visible = 'off';
            end
   
            app.UnitsDropDown.Value = app.UnitsDropDown.Items{strcmpi(app.UnitsDropDown.Items,len_unit)};
            
            if ~isempty(ZG.primeCatalog)
                app.DemoGrid.XLim = [min(ZG.primeCatalog.Longitude) max(ZG.primeCatalog.Longitude)];
                app.DemoGrid.YLim = [min(ZG.primeCatalog.Latitude) max(ZG.primeCatalog.Latitude)];
            else
                app.DemoGrid.XLim = [-180 180];
                app.DemoGrid.YLim = [-90 90];
            end
            
            g = ZG.Grid;
            if ~isempty(g.Name)
                app.GridNameEditField.Value=g.Name;
            end
            if isfield(g,'Origin') && ~isempty(g.Origin)
                app.XAnchor.Value=origin(1);
                app.YAnchor.Value=origin(2);
                if length(g.Origin)==3
                    app.ZLimits.Value=mat2str([origin(3) origin(3)]);
                end
            else
                app.XAnchor.Value=round(mean(app.DemoGrid.XLim),3);
                app.YAnchor.Value=round(mean(app.DemoGrid.YLim),3);
            end
            
            app.AnchorPoint = scatter(app.DemoGrid, app.XAnchor.Value, app.YAnchor.Value,100,'x','LineWidth',2);
            app.AnchorPointPanel.ForegroundColor=app.AnchorPoint.CData;
            
            hold(app.DemoGrid,'on');

            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints = scatter(app.DemoGrid, lonMat(:), latMat(:), 12,'+','LineWidth',1);
            
            hold(app.DemoGrid,'off');
                
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % three distinct regimes exist.
            % 1. going from a length to a degree
            % 2. going from a degree to a length
            % 3. going from one length to another
            old_value = event.PreviousValue;
            new_value = app.UnitsDropDown.Value;
            
            if old_value == "degree"
                % convert to new value
                app.dx.Limits = [0 inf];
                app.dy.Limits = [0 inf];
                my_ratio = unitsratio(new_value, app.RefEllipsoid.LengthUnit);
                app.dx.Value  = deg2km(app.dx.Value) * my_ratio;
                app.dy.Value  = deg2km(app.dy.Value) * my_ratio;
                %app.dz.Value  = app.dz.Value * my_ratio;
                app.RefEllipsoid.LengthUnit = new_value;
                app.FollowMeridiansCheckBox.Visible = 'off';
                
            elseif new_value == "degree"
                my_ratio = unitsratio('kilometer', app.RefEllipsoid.LengthUnit);
                app.FollowMeridiansCheckBox.Visible = 'on';
                app.dx.Value  = km2deg(app.dx.Value .* my_ratio);
                app.dy.Value  = km2deg(app.dy.Value .* my_ratio);
                %app.dz.Value  = app.dz.Value * unitsratio('kilometer', old_value);
                app.RefEllipsoid.LengthUnit = 'kilometer';
                app.dx.Limits = [0 180];
                app.dy.Limits = [0 90];
                
            else
                app.dx.Value = app.dx.Value * unitsratio(new_value, old_value);
                app.dy.Value = app.dy.Value * unitsratio(new_value, old_value);
                % app.dz.Value = app.dz.Value * unitsratio(new_value, old_value);
                app.RefEllipsoid.LengthUnit = new_value;
            end
            
            app.UpdateGridPoints()
        end

        % Value changed function: XAnchor, YAnchor
        function UpdateAnchorPoint(app, event)
            app.AnchorPoint.XData = app.XAnchor.Value;
            app.AnchorPoint.YData = app.YAnchor.Value;
            app.UpdateGridPoints;
        end

        % Value changed function: FollowMeridiansCheckBox, dx, dy
        function UpdateGridPoints(app, event)
            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints.XData=lonMat(:);
            app.GridPoints.YData=latMat(:);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            ZG = ZmapGlobal.Data;
            ZG.gridopt = app.GridOpts;
            ZG.Grid = app.CreateGrid;
            app.DumpTheResult;
            delete(app)
        end

        % Button pushed function: SaveOKButton
        function SaveOKButtonPushed(app, event)
            % first, save grid
            ZG=ZmapGlobal.Data;
            gridopt = app.GridOpts;
            g = app.CreateGrid;
            app.ChooseGridParametersUIFigure.Visible = 'off';
            [file,path,opt] = uiputfile({'*.mat','MATLAB data file';...
                '*.txt','grid points text'},...
                'Save Grid As',...
                fullfile(app.GridDirectory,[app.GridNameEditField.Value,'_grid']));
            
            app.ChooseGridParametersUIFigure.Visible = 'on';
            switch opt
                case 1 % save both as .mat
                    savename = fullfile(path,file);
                    save(savename,'gridopt','g');
                case 2 % save grid points as text
                    error('unimplemented');
                    
                otherwise
                    % don't save, don't close app
                    return
            end
            app.DumpTheResult;
            % next, press the ok button
            delete(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            default = fullfile(app.GridDirectory,'default_grid.mat')
            [file, path,opt] = uigetfile({'*.mat','MATLAB data file';...
                '*.txt','grid points text'},...
                'Load Grid',default);
            loaded=load(fullfile(path,file),'gridopt','g');
            app.SetGridOptions(loaded.g, loaded.gridopt);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            % change viewing angle based on Type
            % activate/deactivate dz based on Type
            % activate/deactivate Z anchor point based on Type
            switch value
                case 'XY'
                    app.DemoGrid.XLabel.String='Longitude';
                    app.DemoGrid.YLabel.String='Latitude';
                    app.DemoGrid.ZLabel.String='Depth';
                    app.DemoGrid.View = [0 90]; % Lat vs Lon
                    app.dz.Enable = 'off';
                    app.dy.Enable = 'on';
                    app.dxEditFieldLabel.Text = 'dx';
                    app.ZLimits.Enable = 'off';
                case 'XZ'
                    app.DemoGrid.XLabel.String='Distance Along Cross Section';
                    app.DemoGrid.YLabel.String='Latitude';
                    app.DemoGrid.ZLabel.String='Depth';
                    app.DemoGrid.View = [0 0]; % Depth vs Lon
                    app.dz.Enable = 'on';
                    app.dxEditFieldLabel.Text = 'dS';
                    app.dy.Enable = 'off';
                    app.ZLimits.Enable = 'on';
                case 'XYZ'
                    app.DemoGrid.XLabel.String='Longitude';
                    app.DemoGrid.YLabel.String='Latitude';
                    app.DemoGrid.ZLabel.String='Depth';
                    app.DemoGrid.View = [-15,30];
                    app.dz.Enable = 'on';
                    app.dxEditFieldLabel.Text = 'dx';
                    app.dy.Enable = 'on';
                    app.ZLimits.Enable = 'on';
            end
            
            app.dzLabel.Enable = app.dz.Enable;
            app.dyEditFieldLabel.Enable = app.dy.Enable;
            app.ZLimitsdownLabel_2.Enable = app.dz.Enable;
        end

        % Value changed function: ZLimits
        function ZLimitsValueChanged(app, event)
            value = app.ZLimits.Value;
            value = str2num(value);
            if value(1)==value(2)
                value = value + [-1 , 1]; % because limits can't be [N N]
            end
            try
                app.DemoGrid.ZLim = value;
                app.ZLimits.BackgroundColor = [1 1 1];
            catch
                beep
                app.ZLimits.BackgroundColor = app.ErrorColor;
                pause(.5)
                % reset to prev value
                app.ZLimits.Value = event.PreviousValue;
                value = str2num(event.PreviousValue);
                if value(1)==value(2)
                    value = value + [-1 , 1]; % because limits can't be [N N]
                end
                app.DemoGrid.ZLim = value;
            end
            app.UpdateGridPoints;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooseGridParametersUIFigure = uifigure;
            app.ChooseGridParametersUIFigure.Name = 'Choose Grid Parameters';
            app.GridNameEditFieldLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.ChooseGridParametersUIFigure, 'text');
            app.GridNameEditField.Value = 'grid';
            app.AnchorPointPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.AnchorPointPanel.Title = 'Anchor Point';
            app.XorLonEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.XorLonEditFieldLabel.Text = 'X or Lon';
            app.XAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.XAnchor.LowerLimitInclusive = 'off';
            app.XAnchor.UpperLimitInclusive = 'off';
            app.XAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.YorLatEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.YorLatEditFieldLabel.Text = 'Y or Lat';
            app.YAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.YAnchor.LowerLimitInclusive = 'off';
            app.YAnchor.UpperLimitInclusive = 'off';
            app.YAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.ZLimitsdownLabel_2 = uilabel(app.AnchorPointPanel);
            app.ZLimitsdownLabel_2.Enable = 'off';
            app.ZLimitsdownLabel_2.Text = {'Z Limits'; '(+down)'};
            app.ZLimits = uieditfield(app.AnchorPointPanel, 'text');
            app.ZLimits.ValueChangedFcn = createCallbackFcn(app, @ZLimitsValueChanged, true);
            app.ZLimits.Enable = 'off';
            app.ZLimits.Value = '[0 0]';
            app.SeparationPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.SeparationPanel.Title = 'Separation';
            app.dxEditFieldLabel = uilabel(app.SeparationPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dx = uieditfield(app.SeparationPanel, 'numeric');
            app.dx.LowerLimitInclusive = 'off';
            app.dx.Limits = [0 Inf];
            app.dx.ValueDisplayFormat = '%.3f';
            app.dx.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dx.Value = 0.5;
            app.dyEditFieldLabel = uilabel(app.SeparationPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dy = uieditfield(app.SeparationPanel, 'numeric');
            app.dy.LowerLimitInclusive = 'off';
            app.dy.Limits = [0 Inf];
            app.dy.ValueDisplayFormat = '%.3f';
            app.dy.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dy.Value = 0.5;
            app.dzLabel = uilabel(app.SeparationPanel);
            app.dzLabel.Enable = 'off';
            app.dzLabel.Text = 'dz';
            app.dz = uieditfield(app.SeparationPanel, 'numeric');
            app.dz.LowerLimitInclusive = 'off';
            app.dz.Limits = [0 Inf];
            app.dz.ValueDisplayFormat = '%.3f';
            app.dz.Enable = 'off';
            app.dz.Value = 5;
            app.UnitsDropDownLabel = uilabel(app.SeparationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.SeparationPanel);
            app.UnitsDropDown.Items = {'degree', 'kilometer', 'meter', 'millimeter', 'micron'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'degree';
            app.FollowMeridiansCheckBox = uicheckbox(app.SeparationPanel);
            app.FollowMeridiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.FollowMeridiansCheckBox.Text = 'Follow Meridians';
            app.OKButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DemoGrid = uiaxes(app.ChooseGridParametersUIFigure);
            title(app.DemoGrid, 'Sample Grid Spacing')
            xlabel(app.DemoGrid, 'Longitude')
            ylabel(app.DemoGrid, 'Latitude')
            app.DemoGrid.XLim = [0 1];
            app.DemoGrid.YLim = [0 1];
            app.DemoGrid.ZDir = 'reverse';
            app.DemoGrid.CLim = [0 1];
            app.DemoGrid.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.DemoGrid.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveOKButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.SaveOKButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOKButtonPushed, true);
            app.SaveOKButton.Text = 'Save & OK';
            app.CancelButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RefEllipsoidLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.RefEllipsoidLabel.Text = 'Using:';
            app.TypeDropDownLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ChooseGridParametersUIFigure);
            app.TypeDropDown.Items = {'Horizontal Plane (XY)', 'Vertical Cross Section (XZ)', 'Volume (XYZ)'};
            app.TypeDropDown.ItemsData = {'XY', 'XZ', 'XYZ'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'XY';
        end
    end

    methods (Access = public)

        % Construct app
        function app = grid_chooser(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooseGridParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooseGridParametersUIFigure)
        end
    end
end